classdef PHASE_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        fig                     matlab.ui.Figure
        uibuttongroup11         matlab.ui.container.ButtonGroup
        txtYaxisMax             matlab.ui.control.EditField
        txtYaxisMin             matlab.ui.control.EditField
        btnYaxisManual          matlab.ui.control.RadioButton
        btnYaxisAuto            matlab.ui.control.RadioButton
        text52                  matlab.ui.control.Label
        pbActiveNormalized      matlab.ui.control.Button
        text14                  matlab.ui.control.Label
        pbFolderOut             matlab.ui.control.Button
        text11                  matlab.ui.control.Label
        txtFolderOut            matlab.ui.control.EditField
        txtPlotTitle            matlab.ui.control.EditField
        uipanel3                matlab.ui.container.Panel
        text3                   matlab.ui.control.Label
        txtRunName              matlab.ui.control.Label
        pbClear                 matlab.ui.control.Button
        lblexpstart             matlab.ui.control.Label
        txtLightHours           matlab.ui.control.EditField
        text6                   matlab.ui.control.Label
        txtLightsOn             matlab.ui.control.EditField
        text7                   matlab.ui.control.Label
        text8                   matlab.ui.control.Label
        text15                  matlab.ui.control.Label
        txtDayLength            matlab.ui.control.EditField
        txtExpStartTime         matlab.ui.control.EditField
        pbExpStart              matlab.ui.control.Button
        txtExpStartDate         matlab.ui.control.EditField
        lstSelections           matlab.ui.control.ListBox
        pbAddChannels           matlab.ui.control.Button
        lstChannels             matlab.ui.control.ListBox
        lstBoards               matlab.ui.control.ListBox
        text9                   matlab.ui.control.Label
        pbActiveEduce           matlab.ui.control.Button
        pbSleepEduce            matlab.ui.control.Button
        text2                   matlab.ui.control.Label
        pbFolderIn              matlab.ui.control.Button
        txtFolderIn             matlab.ui.control.EditField
        pnlAnalysis             matlab.ui.container.Panel
        chkActivitySleep        matlab.ui.control.CheckBox
        chkMultiPlots           matlab.ui.control.CheckBox
        chkSavePlots            matlab.ui.control.CheckBox
        bgPlots                 matlab.ui.container.ButtonGroup
        chkErrorBars            matlab.ui.control.CheckBox
        btnPlotBoth             matlab.ui.control.RadioButton
        btnPlotNew              matlab.ui.control.RadioButton
        btnPlotClassic          matlab.ui.control.RadioButton
        bgAveraging             matlab.ui.container.ButtonGroup
        btnAvgNone              matlab.ui.control.RadioButton
        btnAvgBoth              matlab.ui.control.RadioButton
        btnAvgDays              matlab.ui.control.RadioButton
        btnAvgFlies             matlab.ui.control.RadioButton
        text13                  matlab.ui.control.Label
        text12                  matlab.ui.control.Label
        txtDays                 matlab.ui.control.EditField
        txtBinSize              matlab.ui.control.EditField
        pnlPhase                matlab.ui.container.Panel
        textzt2                 matlab.ui.control.Label
        txtPhaseZT              matlab.ui.control.EditField
        chkPhase                matlab.ui.control.CheckBox
        text40                  matlab.ui.control.Label
        txtPhaseDays            matlab.ui.control.EditField
        txtPhaseMinDistdesc     matlab.ui.control.Label
        txtPhaseMinDist         matlab.ui.control.EditField
        text38                  matlab.ui.control.Label
        txtPhaseFilterLength    matlab.ui.control.EditField
        text37                  matlab.ui.control.Label
        txtPhaseFilterOrder     matlab.ui.control.EditField
        pnlLatency              matlab.ui.container.Panel
        text49                  matlab.ui.control.Label
        txtLatencyDays          matlab.ui.control.EditField
        txtLatencyZTdesc        matlab.ui.control.Label
        txtLatencyZT            matlab.ui.control.EditField
        txtLatencyWindowdesc    matlab.ui.control.Label
        txtLatencyWindow        matlab.ui.control.EditField
        text46                  matlab.ui.control.Label
        txtLatencyFilterLength  matlab.ui.control.EditField
        txtLatencyOrd           matlab.ui.control.Label
        txtLatencyFilterOrder   matlab.ui.control.EditField
        chkLatency              matlab.ui.control.CheckBox
        pbAnalysis              matlab.ui.control.StateButton
        pbPhase                 matlab.ui.control.StateButton
        pbLatency               matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        activePanel = 'Analysis'; % Description
        dataFolder = ''; % Description
        sf = []; % Description
    end
    
    methods (Access = private)
        function value = cleanDecimalInput(~, value)
            value = regexprep(value, '^\d.', '');
        end
        
        function value = cleanIntegerInput(~, value)
            value = regexprep(value, '\D', '');
        end
        
        function value = cleanNumericListInput(~, value)
            value = regexprep(value, '[ ,;]+',',');
            value = regexprep(value, '[^\d,.]','');
        end
        
        function value = cleanOddNumberInput(app, value)
            value = regexprep(value, '\D', '');
            value = str2double(value);
            if mod(value,2) == 0
                % msgbox([label ' must be an odd number.']);
                value = value+1;
            end
        end
        
        function doEduction(app, isSleep, isNormalized)
            % If we didn't already make the experiment object, get the inputs we're
            % going to need to make the experiment class object
            import SleepyFlies.*
            
            folderIn = app.txtFolderIn.Value;
            runName = app.txtRunName.Text;
            dayLength = str2double(app.txtDayLength.Value);
            lightsOn = str2double(app.txtLightsOn.Value);
            lightHours = str2double(app.txtLightHours.Value);
            expStartStr = strcat(app.txtExpStartDate.Value, {' '}, app.txtExpStartTime.Value);
            expStart = datetime(expStartStr);
            bcStrings = app.lstSelections.Value'; %Anything that's been added to this list is what we need to use
            if ~iscell(bcStrings) || isempty(bcStrings) || strcmpi(bcStrings{1}, 'Selected Channels')
                msgbox('Please select at least one board and channel for analysis.', 'No Channels Selected');
                return;
            end
            if app.btnYaxisManual.Value
                % Manual y axis specification
                settings.yrange = [str2double(app.txtYaxisMin) ...
                    str2double(app.txtYaxisMax.Value)];
            else
                settings.yrange = [];
            end
            % Now get the settings for eduction
            switch app.bgAveraging.SelectedObject.Text
                case 'No Averaging'
                    settings.averaging = Averaging.None;
                otherwise
                    settings.averaging = Averaging.(app.bgAveraging.SelectedObject.Text);
            end
            switch app.bgPlots.SelectedObject.Text
                case 'Bars'
                    settings.plotType = PlotType.Bars;
                case 'Lines'
                    settings.plotType = PlotType.Lines;
                case 'Both'
                    settings.plotType = PlotType.Both;
            end
            settings.isSleep = isSleep;
            settings.normalizeActivity = isNormalized;
            settings.binSize = str2double(app.txtBinSize.Value);
            settings.days = sscanf(app.txtDays.Value,'%u,')';
            settings.title = app.txtPlotTitle.Value;
            settings.folderOut = app.txtFolderOut.Value;
            settings.useErrorBars = app.chkErrorBars.Value;
            settings.savePlots = app.chkSavePlots.Value;
            settings.multiPlots = app.chkMultiPlots.Value;
            if app.chkActivitySleep.Value
                settings.doAS = true;
            else
                settings.doAS = false;
            end
            
            % If the time period to observe for latency is specified, do the latency plots;
            % If it's 0, no plots.
            if app.chkLatency.Value
                settings.latencyMinutes = str2double(app.txtLatencyWindow.Value);
                settings.latencyOrder = str2double(app.txtLatencyFilterOrder.Value);
                settings.latencyFrameLen = str2double(app.txtLatencyFilterLength.Value);
                settings.latencyZT = sscanf(app.txtLatencyZT.Value,'%u,')';
                settings.latencyDays = sscanf(app.txtLatencyDays.Value,'%u,')';
                settings.doLatency = true;
            else
                settings.doLatency = false;
            end
            
            % If the time period to observe for phase is specified, do the latency plots;
            % If it's 0, no plots.
            if app.chkPhase.Value
                settings.phaseOrder = str2double(app.txtPhaseFilterOrder.Value);
                settings.phaseFrameLen = str2double(app.txtPhaseFilterLength.Value);
                settings.phaseMinDist = sscanf(app.txtPhaseMinDist.Value,'%u,')';
                settings.phaseDays = sscanf(app.txtPhaseDays.Value,'%u,')';
                settings.phaseZT = sscanf(app.txtPhaseZT.Value,'%u,')';
                settings.doPhase = true;
            else
                settings.doPhase = false;
            end
            
            if settings.savePlots && isempty(settings.folderOut)
                waitfor(msgbox('Please select a folder to save results', 'No Output Folder Selected'));
                settings.folderOut = uigetdir();
                if settings.folderOut ~= 0 % If the user hit cancel, don't do anything
                    set(app.txtFolderOut, 'Value', settings.folderOut);
                else
                    waitfor(msgbox('Educer will continue, but plots will not be saved.'));
                    settings.savePlots = 0;
                    settings.folderOut = []; % This needs to be empty or the educer will try to save statistics
                end
            end
            
            
            % Convert the board/channel strings to numbers
            boardsChans = cell2mat(cellfun(@(x) sscanf(x, 'M%u_C%u', [1 2]), bcStrings, 'uniformoutput', false));
            boards = boardsChans(:, 1)';
            chans = boardsChans(:, 2)';
            try
                experiment = DataExperiment(folderIn, runName, chans, boards, ...
                    lightsOn, lightHours, dayLength, expStart);
            catch ME
                if strcmpi(ME.identifier, 'SleepyFlies:importData:fileNotFound')
                    msgbox(ME.message, 'Invalid Channel');
                    return;
                elseif strcmpi(ME.identifier, 'SleepyFlies:invalidExpStart')
                    msgbox(ME.message, 'Invalid Experiment Start');
                    return;
                else
                    msgbox([ME.identifier ':  ' ME.message], 'Unexpected Error');
                    ME(1).stack(1)
                    return;
                end
            end


            % And do the eduction
            %set(app.fig, 'pointer', 'watch');
            drawnow
            try
                if settings.doAS
                    analysis = DataForAnalysisAS(experiment, 'days',settings.days, ...
                        'averaging', settings.averaging, 'binSize', settings.binSize, ...
                        'isSleep', isSleep, 'title', settings.title, 'normalizeActivity', isNormalized);
                    if analysis.DeadFlies
                        msgbox(analysis.DeadFlies, 'Warning: Dead Flies', 'modal');
                    end
                    % TODO: Clean up settings to only have actual plot settings here eventually
                    analysis.plotData(settings);
                    % 2019-01-21 Change of plans: now the all-the-days pages will
                    % be written as averaged data, but the individual days will
                    % still be unaveraged. This goes for all analysis types.
                    analysis.writeDataToFile(settings.folderOut);
                    % Also write each individual day of data.
                    % (Previously this was only done with averaging type None.)
                    % Do this before the individual days, so that when the settings
                    % page gets written it includes all of the days.
                    % if settings.averaging == Averaging.None
                    for d=1:numel(settings.days)
                        day = settings.days(d);
                        day_analysis = DataForAnalysisAS(experiment, 'days', day, ...
                            'averaging', settings.averaging, 'binSize', settings.binSize,...
                            'isSleep', isSleep, 'title', settings.title, 'normalizeActivity', isNormalized);
                        day_analysis.writeDataToFile(settings.folderOut);
                    end
                    % end
                    %analysis.writeDataToFile(settings.folderOut);
                    % Jenna only wants the no averaging output to Excel now (Sept. 2018), so do that
                    % here instead.
                    % analysis = DataForAnalysisAS(experiment, 'days', settings.days, ...
                    %     'averaging', Averaging.None, 'binSize', settings.binSize, ...
                    %     'isSleep', isSleep, 'title', settings.title, 'normalizeActivity', isNormalized);
                    % analysis.writeDataToFile(settings.folderOut);
                end
            
                if settings.doLatency
                    latency = DataForAnalysisLatency(experiment, ...
                        'days', settings.latencyDays,...
                        'averaging', Averaging.Days, ...
                        'filterOrder', settings.latencyOrder, ...
                        'frameLength', settings.latencyFrameLen,...
                        'windowMinutes', settings.latencyMinutes,...
                        'windowZTs', settings.latencyZT,...
                        'isSleep', isSleep,...
                        'title', settings.title,...
                        'normalizeActivity', isNormalized);
                    latency.writeDataToFile(settings.folderOut);
                    latency.plotData(settings);
                end
                if settings.doPhase
                    % Note that here and in latency, the input averaging & bin size
                    % aren't actually used--they're overridden in the constructor.
                    phase = DataForAnalysisPhase(experiment, ...
                        'days', settings.phaseDays,...
                        'averaging', Averaging.Days, ...
                        'filterOrder', settings.phaseOrder, ...
                        'frameLength', settings.phaseFrameLen,...
                        'minPeakDist', settings.phaseMinDist,...
                        'phaseZT', settings.phaseZT,...
                        'isSleep', isSleep,...
                        'title', settings.title,...
                        'normalizeActivity', isNormalized);
                    phase.writeDataToFile(settings.folderOut);
                    phase.plotData(settings);
                end
            catch ME
                if ~isempty(ME.cause)
                    msg = ME.cause{1}.message;
                    icon = 'warn';
                else
                    msg = [ME.identifier ':  ' ME.message];
                    icon = 'error';
                end
                msgbox(msg, 'Error', icon);
                for i = 1:size(ME(1).stack,1)
                    disp(ME(1).stack(i))
                end
                set(app.fig, 'pointer', 'arrow');
                return
            end
            
            set(app.fig, 'pointer', 'arrow');
            drawnow
        end
        
        function enableAS(app, setting)
            set(app.btnAvgFlies, 'enable', setting);
            set(app.btnAvgDays, 'enable', setting);
            set(app.btnAvgBoth, 'enable', setting);
            set(app.btnAvgNone, 'enable', setting);
            set(app.btnPlotClassic, 'enable', setting);
            set(app.btnPlotNew, 'enable', setting);
            set(app.btnPlotBoth, 'enable', setting);
            set(app.txtBinSize, 'enable', setting);
            set(app.txtDays, 'enable', setting);
        end
        
        function enableLatency(app, setting)
            set(app.txtLatencyFilterOrder, 'enable', setting);
            set(app.txtLatencyFilterLength, 'enable', setting);
            set(app.txtLatencyWindow, 'enable', setting);
            set(app.txtLatencyZT, 'enable', setting);
            set(app.txtLatencyDays, 'enable', setting);
        end
        
        function enablePhase(app, setting)
            % setting should be either 'on' or 'off'
            set(app.txtPhaseFilterOrder,'enable',setting);
            set(app.txtPhaseFilterLength, 'enable', setting);
            set(app.txtPhaseMinDist, 'enable', setting);
            set(app.txtPhaseDays, 'enable', setting)
            set(app.txtPhaseZT, 'enable', setting);
        end
        
        function [boards, channels] = getBoardsAndChannels(~, ~, fileList)
            fileList = {fileList(:).name};
            pattern = '\w+M(\d{3})C(\d{2}).*';
            boardsChans = regexp(fileList, pattern, 'tokens');
            boardsChans = boardsChans(cellfun(@(x) ~isempty(x), boardsChans));
            boardsChans = vertcat(boardsChans{:});
            boardsChans = vertcat(boardsChans{:});
            boards = unique(boardsChans(:,1));
            channels = unique(boardsChans(:,2));
        end
        
        function openDataFolder(app, folderIn)
            import SleepyFlies.*
            if ~strcmp(app.dataFolder, folderIn)
                % If the folder changed, clear the existing SleepyFlies class object,
                % since this is new data.
                % app.sf = [];
                app.dataFolder = folderIn;
                if isfolder(folderIn)
                    openDataFolder(app, folderIn);
                else
                    msgbox('Please enter a valid folder path.', 'Invalid path');
                    return
                end
                try
                    files = dir(folderIn);
                    % Try to figure out the file name pattern
                    sampleName = [];
                    i = 3;  % The first two listed files are likely . and ..
                    while isempty(sampleName) && i <= size(files,1)
                        if ~files(i).isdir && ~strcmp(files(i).name(1), '.')
                            sampleName = files(i).name;
                        end
                        i = i+1;
                    end
                    pattern = '(\w+)M\d{3}C\d{2}.*';
                    fileString = regexp(sampleName, pattern, 'tokens');
                    if ~isempty(fileString)
                        app.txtRunName.Text = fileString{1}{1};
                        [boards, channels] = getBoardsAndChannels(app, fileString{1}{1}, files);
                        app.lstBoards.Items = boards;
                        app.lstChannels.Items = channels;
                        app.lstChannels.Value = channels;
                        % While we're at it, see if we can get a date to start on
                        [dateStr, timeStr] = DataRaw.getExperimentDate(fullfile(folderIn, sampleName));
                        app.txtExpStartDate.Value = dateStr{1};
                        app.txtExpStartTime.Value = timeStr;
                    end
                catch
                    % Ignore it.
                end
            end
        end
        
        function setYaxisStates(app, enabled)
            set(app.txtYaxisMin,'enable',enabled);
            set(app.txtYaxisMax,'enable',enabled);
        end
        
        function togglePanel(app, activePanel)
            % Hide the current active panel, turn on the selected panel, and update the
            % active panel variable
            set(app.(['pnl' app.activePanel]), 'visible', 'off');
            set(app.(['pb' app.activePanel]), 'value', 1);
            set(app.(['pnl' activePanel]), 'visible','on');
            set(app.(['pb' activePanel]), 'value', 0);
            app.activePanel = activePanel;
        end
        
%         function txtLatencyMinutes_Callback(app, hObject, eventdata)
%             cleanIntegerInput(hObject);
%         end
        
%         function txtLatencyMinutes_CreateFcn(app, eventdata)
%             % hObject    handle to txtLatencyMinutes (see GCBO)
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    empty - handles not created until after all CreateFcns called
%             
%             % Hint: edit controls usually have a white background on Windows.
%             %       See ISPC and COMPUTER.
%             if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
%                 set(hObject,'BackgroundColor','white');
%             end
%         end
        
        function txtLatencyWindowdesc_CreateFcn(app, ~)
            % hObject    handle to txtLatencyWindowdesc (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(app.txtLatencyWindowdesc.BackgroundColor, get(0,'defaultUicontrolBackgroundColor'))
                app.txtLatencyWindowdesc.BackgroundColor = 'white';
            end
        end
        
        function txtLatencyZTdesc_CreateFcn(app, ~)
            % hObject    handle to txtLatencyZTdesc (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(app.txtLatencyZTdesc.BackgroundColor, get(0,'defaultUicontrolBackgroundColor'))
                app.txtLatencyZTdesc.BackgroundColor = 'white';
            end
        end
        
        function txtPhaseMinDistdesc_CreateFcn(app, ~)
            % hObject    handle to txtPhaseMinDistdesc (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(app.txtPhaseMinDistdesc.BackgroundColor, get(0,'defaultUicontrolBackgroundColor'))
                app.txtPhaseMinDistdesc.BackgroundColor = 'white';
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function PHASE_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to PHASE (see VARARGIN)
%             
%             % add the SleepyFlies and SupportFiles folders to the path
%             % Determine where your m-file's folder is.
             folder = fileparts(which(mfilename));
%             % Add that folder plus all subfolders to the path.
             if ~isdeployed
                 addpath(genpath(fullfile(folder, 'SleepyFlies')));
                 addpath(genpath(fullfile(folder, 'SupportFiles')));
             end
%             
%             % Populate the board list
%             boards = 1:300;
%             boards = arrayfun(@(x) sprintf("%03d", x), boards');
%             set(app.lstBoards, 'String', boards);
%             channels = 1:32;
%             channels = arrayfun(@(x) sprintf("%02d", x), channels');
%             set(app.lstChannels, 'String', channels);
%             
%             %Initialize the SleepyFlies class variable and data folder to empty
             app.sf = [];
             app.dataFolder = [];
%             
%             % Initialize the active panel
%             app.activePanel = 'Analysis';
%             
%             % Choose default command line output for PHASE
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);
%             
%             
%             % If we're on a Mac, make sure the poi libraries are on the java class path
             if ~ispc && ~isdeployed
                % Add Java POI Libs to matlab javapath if necessary
                if exist('org.apache.poi.ss.usermodel.WorkbookFactory', 'class') ~= 8 ...
                        || exist('org.apache.poi.hssf.usermodel.HSSFWorkbook', 'class') ~= 8 ...
                        || exist('org.apache.poi.xssf.usermodel.XSSFWorkbook', 'class') ~= 8
                    appdir = fileparts(mfilename('fullpath'));
                    javaaddpath(fullfile(appdir, 'SupportFiles', 'poi_library','poi-3.8-20120326.jar'));
                    javaaddpath(fullfile(appdir, 'SupportFiles', 'poi_library','poi-ooxml-3.8-20120326.jar'));
                    javaaddpath(fullfile(appdir, 'SupportFiles', 'poi_library','poi-ooxml-schemas-3.8-20120326.jar'));
                    javaaddpath(fullfile(appdir, 'SupportFiles', 'poi_library','xmlbeans-2.3.0.jar'));
                    javaaddpath(fullfile(appdir, 'SupportFiles', 'poi_library','dom4j-1.6.1.jar'));
                    javaaddpath(fullfile(appdir, 'SupportFiles', 'poi_library','stax-api-1.0.1.jar'));
                end
             end
            
            if ~license('test', 'Financial_Toolbox')
                %The calendar UI won't work, so don't show the button
                set(app.pbExpStart, 'visible', 'off');
            end
        
%             %% DEBUG Setup
%             DEBUG = false;
%             if DEBUG
%                 set(app.txtFolderOut, 'string', '/Users/aheinlei/work/mcdb-shafer-sleepyflies/TestOutputsNew');
%                 pbFolderIn_Callback(hObject, eventdata, handles);
%             end
%             
%             if ispc
%                 fieldList = fieldnames(handles);
%                 for fIdx = 1:numel(fieldList)
%                     if isa(app.(fieldList{fIdx}), 'matlab.ui.control.UIControl')
%                         try
%                             if app.(fieldList{fIdx}).FontSize==10
%                                 app.(fieldList{fIdx}).FontSize=8;
%                             end
%                         catch
%                             continue
%                         end
%                     end
%                 end
%             end
        end

        % Value changed function: chkActivitySleep
        function chkActivitySleep_Callback(app, event)
            if app.chkActivitySleep.Value
                setting = 'on';
            else
                setting = 'off';
            end
            enableAS(app, setting);
        end

        % Value changed function: chkLatency
        function chkLatency_Callback(app, event)
            if app.chkLatency.Value
                setting = 'on';
            else
                setting = 'off';
            end
            enableLatency(app, setting);
        end

        % Value changed function: chkPhase
        function chkPhase_Callback(app, event)
            if app.chkPhase.Value
                setting = 'on';
            else
                setting = 'off';
            end
            enablePhase(app, setting);
        end

        % Button pushed function: pbActiveEduce
        function pbActiveEduce_Callback(app, event)
            % hObject    handle to pbActiveEduce (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            doEduction(app, 0,0);
        end

        % Button pushed function: pbActiveNormalized
        function pbActiveNormalized_Callback(app, event)
            % hObject    handle to pbActiveNormalized (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            doEduction(app, 0,1);
        end

        % Button pushed function: pbAddChannels
        function pbAddChannels_Callback(app, event)
            % Copy the selected board & channels over to the selected listbox
            % Also store them in the handles structure
            currentSelected = cellstr(app.lstSelections.Value);
            if ~isempty(currentSelected) && strcmpi(currentSelected{1}, 'Selected Channels')
                currentSelected = {};
            end
            selBoardNames = app.lstBoards.Value;
            selChanNames = app.lstChannels.Value;
            boardCount = numel(selBoardNames);
            chanCount = numel(selBoardNames);
            b = repmat(selBoardNames, 1, chanCount);
            c = shiftdim(repmat(selChanNames, 1, boardCount),1);
            selectedList = strcat('M', b, '_C', c);
            selectedList = sort(reshape(selectedList, [], 1));
            set(app.lstSelections, 'Items', unique([currentSelected'; selectedList]));
            set(app.lstSelections, 'Value', app.lstSelections.Items);
        end

        % Value changed function: pbAnalysis
        function pbAnalysis_Callback(app, event)
            if app.pbAnalysis.Value
                app.pbAnalysis.Value = 0;
            else
                togglePanel(app, 'Analysis');
            end
        end

        % Button pushed function: pbClear
        function pbClear_Callback(app, event)
            % hObject    handle to pbClear (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %set(app.lstSelections,'value',[]);
            set(app.lstSelections, 'Items', {'Selected Channels'});
        end

        % Button pushed function: pbExpStart
        function pbExpStart_Callback(app, event)
            %https://www.mathworks.com/help/finance/uicalendar-graphical-user-interface.html#brald_4
            % Open a calendar to choose the date
            % uicalendar requires Financial Toolbox. If it's not installed, don't try
            % this.
            if license('test', 'Financial_Toolbox')
                defaultDate = app.txtExpStartDate.Value;
                if ~isempty(defaultDate)
                    defaultDate = datetime(defaultDate);
                else
                    defaultDate = now;
                end
                uicalendar('SelectionType', 1, 'DestinationUI', {app.txtExpStartDate,'Value'},...
                    'OutputDateFormat', 'dd-mmm-yyyy', 'InitDate', defaultDate);
                % Clear the class variable
            end
        end

        % Button pushed function: pbFolderIn
        function pbFolderIn_Callback(app, event)
            % Open a file dialog
            newFolder = uigetdir();
            if newFolder ~= 0 % If the user hit cancel, don't do anything
                app.txtFolderIn.Value = newFolder;
                openDataFolder(app, newFolder);
            end
        end

        % Button pushed function: pbFolderOut
        function pbFolderOut_Callback(app, event)
            % hObject    handle to pbFolderOut (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Open a file dialog
            folderOut = uigetdir();
            if folderOut ~= 0 % If the user hit cancel, don't do anything
                set(app.txtFolderOut, 'Value', folderOut);
            end
        end

        % Value changed function: pbLatency
        function pbLatency_Callback(app, event)
            if app.pbLatency.Value
                % Don't allow toggling OFF a tab
                app.pbLatency.Value = 0;
            else
                togglePanel(app, 'Latency');
            end
        end

        % Value changed function: pbPhase
        function pbPhase_Callback(app, event)
            if app.pbPhase.Value
                app.pbPhase.Value = 0;
            else
                togglePanel(app, 'Phase');
            end
        end

        % Button pushed function: pbSleepEduce
        function pbSleepEduce_Callback(app, event)
            % hObject    handle to pbSleepEduce (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            doEduction(app, 1,0);
        end

        % Value changed function: txtBinSize
        function txtBinSize_Callback(app, event)
            app.txtBinSize.Value = cleanIntegerInput(app, event.Value);
        end

        % Value changed function: txtDayLength
        function txtDayLength_Callback(app, event)
            % hObject    handle to txtDayLength (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Clear the class variable
            % app.sf = [];
        end

        % Value changed function: txtDays
        function txtDays_Callback(app, event)
            % Replace commas, spaces, semicolons with a single space
            app.txtDays.Value = cleanNumericListInput(app, event.Value);
        end

        % Value changed function: txtExpStartTime
        function txtExpStartTime_Callback(app, event)
            % hObject    handle to txtExpStartTime (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Clear the class variable
            % app.sf = [];
        end

        % Value changed function: txtFolderIn
        function txtFolderIn_Callback(app, event)
            % hObject    handle to txtFolderIn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            newFolder = app.txtFolderIn.Value;
            openDataFolder(app, newFolder);
        end

        % Value changed function: txtLatencyDays
        function txtLatencyDays_Callback(app, event)
            app.txtLatencyDays.Value = cleanNumericListInput(app, event.Value);
        end

        % Value changed function: txtLatencyFilterLength
        function txtLatencyFilterLength_Callback(app, event)
            app.txtLatencyFilterLength.Value = num2str(cleanOddNumberInput(app, event.Value));
        end

        % Value changed function: txtLatencyFilterOrder
        function txtLatencyFilterOrder_Callback(app, event)
            app.txtLatencyFilterOrder.Value = cleanIntegerInput(app, event.Value);
        end

        % Value changed function: txtLatencyWindow
        function txtLatencyWindow_Callback(app, event)
            app.txtLatencyWindow.Value = cleanIntegerInput(app, event.Value);
        end

        % Value changed function: txtLatencyZT
        function txtLatencyZT_Callback(app, event)
            app.txtLatencyZT.Value = cleanNumericListInput(app, event.Value);
        end

        % Value changed function: txtLightHours
        function txtLightHours_Callback(app, event)
            % hObject    handle to txtLightHours (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % app.sf = [];
        end

        % Value changed function: txtLightsOn
        function txtLightsOn_Callback(app, event)
            % hObject    handle to txtLightsOn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % app.sf = [];
        end

        % Value changed function: txtPhaseDays
        function txtPhaseDays_Callback(app, event)
            app.txtPhaseDays.Value = cleanNumericListInput(app, event.Value);
        end

        % Value changed function: txtPhaseFilterLength
        function txtPhaseFilterLength_Callback(app, event)
            app.txtPhaseFilterLength.Value = num2str(cleanOddNumberInput(app, event.Value));
        end

        % Value changed function: txtPhaseFilterOrder
        function txtPhaseFilterOrder_Callback(app, event)
            app.txtPhaseFilterOrder.Value = cleanIntegerInput(app, event.Value);
        end

        % Value changed function: txtPhaseMinDist
        function txtPhaseMinDist_Callback(app, event)
            app.txtPhaseMinDist.Value = cleanIntegerInput(app, event.Value);
        end

        % Value changed function: txtPhaseZT
        function txtPhaseZT_Callback(app, event)
            app.txtPhaseZT.Value = cleanNumericListInput(app, event.Value);
        end

        % Selection changed function: uibuttongroup11
        function uibuttongroup11SelectionChanged(app, event)
            selectedButton = app.uibuttongroup11.SelectedObject;
            if strcmp(selectedButton.Text, 'Specify Y Axis Range:')
                enable=1;
            else
                enable=0;
            end
            setYaxisStates(app, enable);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.fig = uifigure('Visible', 'off');
            app.fig.Name = 'PHASE';
            app.pbLatency = uibutton(app.fig, 'state');
            app.pbLatency.ValueChangedFcn = createCallbackFcn(app, @pbLatency_Callback, true);
            app.pbLatency.Text = 'Anticipation or Latency';
            app.pbLatency.Value = true;
            app.pbPhase = uibutton(app.fig, 'state');
            app.pbPhase.ValueChangedFcn = createCallbackFcn(app, @pbPhase_Callback, true);
            app.pbPhase.Text = 'Phase Analysis';
            app.pbPhase.Value = true;
            app.pbAnalysis = uibutton(app.fig, 'state');
            app.pbAnalysis.ValueChangedFcn = createCallbackFcn(app, @pbAnalysis_Callback, true);
            app.pbAnalysis.Text = 'Activity or Sleep';
            app.pnlLatency = uipanel(app.fig);
            app.pnlLatency.Visible = 'off';
            app.chkLatency = uicheckbox(app.pnlLatency);
            app.chkLatency.ValueChangedFcn = createCallbackFcn(app, @chkLatency_Callback, true);
            app.chkLatency.Text = 'Include Latency or Anticipation Analysis';
            app.txtLatencyFilterOrder = uieditfield(app.pnlLatency, 'text');
            app.txtLatencyFilterOrder.ValueChangedFcn = createCallbackFcn(app, @txtLatencyFilterOrder_Callback, true);
            app.txtLatencyFilterOrder.Enable = 'off';
            app.txtLatencyFilterOrder.Tooltip = 'Filter order for Savitzky-Golay smoothing filter (must be an odd number)';
            app.txtLatencyFilterOrder.Value = '3';
            app.txtLatencyOrd = uilabel(app.pnlLatency);
            app.txtLatencyOrd.Tooltip = 'Filter order for Savitzky-Golay smoothing filter (must be an odd number)';
            app.txtLatencyOrd.Text = 'Filter Order';
            app.txtLatencyFilterLength = uieditfield(app.pnlLatency, 'text');
            app.txtLatencyFilterLength.ValueChangedFcn = createCallbackFcn(app, @txtLatencyFilterLength_Callback, true);
            app.txtLatencyFilterLength.Enable = 'off';
            app.txtLatencyFilterLength.Tooltip = 'Frame length for Savitzky-Golay smoothing filter';
            app.txtLatencyFilterLength.Value = '241';
            app.text46 = uilabel(app.pnlLatency);
            app.text46.Tooltip = 'Frame length for Savitzky-Golay smoothing filter';
            app.text46.Text = 'Filter Frame Length (minutes)';
            app.txtLatencyWindow = uieditfield(app.pnlLatency, 'text');
            app.txtLatencyWindow.ValueChangedFcn = createCallbackFcn(app, @txtLatencyWindow_Callback, true);
            app.txtLatencyWindow.Enable = 'off';
            app.txtLatencyWindow.Tooltip = 'Length of period to be inspected for activity or latency';
            app.txtLatencyWindow.Value = '180';
            app.txtLatencyWindowdesc = uilabel(app.pnlLatency);
            app.txtLatencyWindowdesc.Tooltip = 'Length of period to be inspected for activity or latency';
            app.txtLatencyWindowdesc.Text = 'Window Length (minutes)';
            app.txtLatencyZT = uieditfield(app.pnlLatency, 'text');
            app.txtLatencyZT.ValueChangedFcn = createCallbackFcn(app, @txtLatencyZT_Callback, true);
            app.txtLatencyZT.Enable = 'off';
            app.txtLatencyZT.Tooltip = 'ZT at which latency/anticipation window starts, separated by commas';
            app.txtLatencyZT.Value = '0, 12';
            app.txtLatencyZTdesc = uilabel(app.pnlLatency);
            app.txtLatencyZTdesc.Tooltip = 'ZT at which latency/anticipation window starts, separated by commas';
            app.txtLatencyZTdesc.Text = 'ZT (hours)';
            app.txtLatencyDays = uieditfield(app.pnlLatency, 'text');
            app.txtLatencyDays.ValueChangedFcn = createCallbackFcn(app, @txtLatencyDays_Callback, true);
            app.txtLatencyDays.Enable = 'off';
            app.txtLatencyDays.Tooltip = 'Day(s) to include in latency/anticipation analysis. If specifying multiple days, separate with commas.';
            app.txtLatencyDays.Value = '1,2,3';
            app.text49 = uilabel(app.pnlLatency);
            app.text49.Tooltip = 'Day(s) to include in latency/anticipation analysis. If specifying multiple days, separate with commas.';
            app.text49.Text = 'Days';
            app.pnlPhase = uipanel(app.fig);
            app.pnlPhase.Visible = 'off';
            app.txtPhaseFilterOrder = uieditfield(app.pnlPhase, 'text');
            app.txtPhaseFilterOrder.ValueChangedFcn = createCallbackFcn(app, @txtPhaseFilterOrder_Callback, true);
            app.txtPhaseFilterOrder.Enable = 'off';
            app.txtPhaseFilterOrder.Tooltip = 'Bin size in minutesFilter order for Savitzky-Golay smoothing filter';
            app.txtPhaseFilterOrder.Value = '3';
            app.text37 = uilabel(app.pnlPhase);
            app.text37.Tooltip = 'Filter order for Savitzky-Golay smoothing filter (must be an odd number)';
            app.text37.Text = 'Filter Order';
            app.txtPhaseFilterLength = uieditfield(app.pnlPhase, 'text');
            app.txtPhaseFilterLength.ValueChangedFcn = createCallbackFcn(app, @txtPhaseFilterLength_Callback, true);
            app.txtPhaseFilterLength.Enable = 'off';
            app.txtPhaseFilterLength.Tooltip = 'Frame length for Savitzky-Golay smoothing filter';
            app.txtPhaseFilterLength.Value = '241';
            app.text38 = uilabel(app.pnlPhase);
            app.text38.Tooltip = 'Frame length for Savitzky-Golay smoothing filter';
            app.text38.Text = 'Filter Frame Length (minutes)';
            app.txtPhaseMinDist = uieditfield(app.pnlPhase, 'text');
            app.txtPhaseMinDist.ValueChangedFcn = createCallbackFcn(app, @txtPhaseMinDist_Callback, true);
            app.txtPhaseMinDist.Enable = 'off';
            app.txtPhaseMinDist.Tooltip = 'Minimum distance (in minutes) between peaks to be considered separate peaks.';
            app.txtPhaseMinDist.Value = '180';
            app.txtPhaseMinDistdesc = uilabel(app.pnlPhase);
            app.txtPhaseMinDistdesc.Tooltip = 'Minimum distance (in minutes) between peaks to be considered separate peaks.';
            app.txtPhaseMinDistdesc.Text = 'Minimum Distance Between Peaks (minutes)';
            app.txtPhaseDays = uieditfield(app.pnlPhase, 'text');
            app.txtPhaseDays.ValueChangedFcn = createCallbackFcn(app, @txtPhaseDays_Callback, true);
            app.txtPhaseDays.Enable = 'off';
            app.txtPhaseDays.Tooltip = 'Day(s) to include in phase analysis. If specifying multiple days, separate with commas.';
            app.txtPhaseDays.Value = '1,2,3';
            app.text40 = uilabel(app.pnlPhase);
            app.text40.Tooltip = 'Day(s) to include in phase analysis. If specifying multiple days, separate with commas.';
            app.text40.Text = 'Days';
            app.chkPhase = uicheckbox(app.pnlPhase);
            app.chkPhase.ValueChangedFcn = createCallbackFcn(app, @chkPhase_Callback, true);
            app.chkPhase.Text = 'Include Phase Analysis';
            app.txtPhaseZT = uieditfield(app.pnlPhase, 'text');
            app.txtPhaseZT.ValueChangedFcn = createCallbackFcn(app, @txtPhaseZT_Callback, true);
            app.txtPhaseZT.Enable = 'off';
            app.txtPhaseZT.Tooltip = 'Comma-separated list of ZTs to search for nearest peak locations. If entered, only the peak nearest each entered ZT will be returned. If blank, all peaks will be returned.';
            app.txtPhaseZT.Value = '0,12';
            app.textzt2 = uilabel(app.pnlPhase);
            app.textzt2.Tooltip = 'ZT to search for peaks, separated by a comma';
            app.textzt2.Text = 'ZT (hours)';
            app.pnlAnalysis = uipanel(app.fig);
            app.txtBinSize = uieditfield(app.pnlAnalysis, 'text');
            app.txtBinSize.ValueChangedFcn = createCallbackFcn(app, @txtBinSize_Callback, true);
            app.txtBinSize.Tooltip = 'Bin size in minutes';
            app.txtBinSize.Value = '30';
            app.txtDays = uieditfield(app.pnlAnalysis, 'text');
            app.txtDays.ValueChangedFcn = createCallbackFcn(app, @txtDays_Callback, true);
            app.txtDays.Tooltip = 'Days to include in analysis. If specifying multiple days, separate with commas.';
            app.txtDays.Value = '1,2,3';
            app.text12 = uilabel(app.pnlAnalysis);
            app.text12.Tooltip = 'Bin size in minutes';
            app.text12.Text = 'Bin Size (minutes):';
            app.text13 = uilabel(app.pnlAnalysis);
            app.text13.Tooltip = 'Days to include in analysis. If specifying multiple days, separate with commas.';
            app.text13.Text = 'Days:';
            app.bgAveraging = uibuttongroup(app.pnlAnalysis);
            app.bgAveraging.Title = 'Averaging';
            app.btnAvgFlies = uiradiobutton(app.bgAveraging);
            app.btnAvgFlies.Text = 'Flies';
            app.btnAvgFlies.Value = true;
            app.btnAvgDays = uiradiobutton(app.bgAveraging);
            app.btnAvgDays.Text = 'Days';
            app.btnAvgBoth = uiradiobutton(app.bgAveraging);
            app.btnAvgBoth.Text = 'Both';
            app.btnAvgNone = uiradiobutton(app.bgAveraging);
            app.btnAvgNone.Text = 'No Averaging';
            app.bgPlots = uibuttongroup(app.pnlAnalysis);
            app.bgPlots.Title = 'Plot Style';
            app.btnPlotClassic = uiradiobutton(app.bgPlots);
            app.btnPlotClassic.Text = 'Bars';
            app.btnPlotClassic.Value = true;
            app.btnPlotNew = uiradiobutton(app.bgPlots);
            app.btnPlotNew.Text = 'Lines';
            app.btnPlotBoth = uiradiobutton(app.bgPlots);
            app.btnPlotBoth.Text = 'Both';
            app.chkErrorBars = uicheckbox(app.bgPlots);
            app.chkErrorBars.Visible = 'off';
            app.chkErrorBars.Text = 'Include Error Bars';
            app.chkErrorBars.Value = true;
            app.chkSavePlots = uicheckbox(app.pnlAnalysis);
            app.chkSavePlots.Visible = 'off';
            app.chkSavePlots.Text = 'Save Plots to Output Folder';
            app.chkSavePlots.Value = true;
            app.chkMultiPlots = uicheckbox(app.pnlAnalysis);
            app.chkMultiPlots.Visible = 'off';
            app.chkMultiPlots.Text = 'Multiple Plots per Page';
            app.chkMultiPlots.Value = true;
            app.chkActivitySleep = uicheckbox(app.pnlAnalysis);
            app.chkActivitySleep.ValueChangedFcn = createCallbackFcn(app, @chkActivitySleep_Callback, true);
            app.chkActivitySleep.Text = 'Include Activity/Sleep Analysis';
            app.chkActivitySleep.Value = true;
            app.txtFolderIn = uieditfield(app.fig, 'text');
            app.txtFolderIn.ValueChangedFcn = createCallbackFcn(app, @txtFolderIn_Callback, true);
            app.pbFolderIn = uibutton(app.fig, 'push');
            app.pbFolderIn.ButtonPushedFcn = createCallbackFcn(app, @pbFolderIn_Callback, true);
            app.pbFolderIn.Text = '...';
            app.text2 = uilabel(app.fig);
            app.text2.Text = 'Select Data Folder:';
            app.pbSleepEduce = uibutton(app.fig, 'push');
            app.pbSleepEduce.ButtonPushedFcn = createCallbackFcn(app, @pbSleepEduce_Callback, true);
            app.pbSleepEduce.Text = 'Sleep Analysis';
            app.pbActiveEduce = uibutton(app.fig, 'push');
            app.pbActiveEduce.ButtonPushedFcn = createCallbackFcn(app, @pbActiveEduce_Callback, true);
            app.pbActiveEduce.Text = 'Averaged Activity Analysis';
            app.text9 = uilabel(app.fig);
            app.text9.Text = 'PHASE v7.0';
            app.uipanel3 = uipanel(app.fig);
            app.uipanel3.Title = 'Data Settings';
            app.lstBoards = uilistbox(app.uipanel3);
            app.lstBoards.Items = {'Boards'};
            app.lstBoards.Multiselect = 'on';
            app.lstBoards.Value = {'Boards'};
            app.lstChannels = uilistbox(app.uipanel3);
            app.lstChannels.Items = {'Channels'};
            app.lstChannels.Multiselect = 'on';
            app.lstChannels.Value = {'Channels'};
            app.pbAddChannels = uibutton(app.uipanel3, 'push');
            app.pbAddChannels.ButtonPushedFcn = createCallbackFcn(app, @pbAddChannels_Callback, true);
            app.pbAddChannels.Text = '>';
            app.lstSelections = uilistbox(app.uipanel3);
            app.lstSelections.Items = {'Selected Channels'};
            app.lstSelections.Multiselect = 'on';
            app.lstSelections.Value = {'Selected Channels'};
            app.txtExpStartDate = uieditfield(app.uipanel3, 'text');
            app.txtExpStartDate.Enable = 'off';
            app.txtExpStartDate.Tooltip = 'Experiment start date';
            app.pbExpStart = uibutton(app.uipanel3, 'push');
            app.pbExpStart.ButtonPushedFcn = createCallbackFcn(app, @pbExpStart_Callback, true);
            app.pbExpStart.Tooltip = 'Click to choose experiment start date.';
            app.pbExpStart.Text = '...';
            app.txtExpStartTime = uieditfield(app.uipanel3, 'text');
            app.txtExpStartTime.ValueChangedFcn = createCallbackFcn(app, @txtExpStartTime_Callback, true);
            app.txtExpStartTime.Tooltip = {'Start time of the experiment, in 24-hour HH:MM format (adjust this to center your data appropriately)'};
            app.txtExpStartTime.Value = '08:00';
            app.txtDayLength = uieditfield(app.uipanel3, 'text');
            app.txtDayLength.ValueChangedFcn = createCallbackFcn(app, @txtDayLength_Callback, true);
            app.txtDayLength.Tooltip = 'Day length in hours';
            app.txtDayLength.Value = '24';
            app.text15 = uilabel(app.uipanel3);
            app.text15.Text = 'Exp. Start Date:';
            app.text8 = uilabel(app.uipanel3);
            app.text8.Text = 'Period Length:';
            app.text7 = uilabel(app.uipanel3);
            app.text7.Text = 'Hours of Zeitgeber:';
            app.txtLightsOn = uieditfield(app.uipanel3, 'text');
            app.txtLightsOn.ValueChangedFcn = createCallbackFcn(app, @txtLightsOn_Callback, true);
            app.txtLightsOn.Tooltip = {'Hours after the experiment start time that the zeitgeber turns ON (units must be in decimal values, e.g. 2.5 not 2:30 if lights turn ON two and a half hours after the experiment start time)'};
            app.txtLightsOn.Value = '0';
            app.text6 = uilabel(app.uipanel3);
            app.text6.Tooltip = {'Hours after the experiment start time that the zeitgeber turns ON (units must be in decimal values, e.g. 2.5 not 2:30 if lights turn ON two and a half hours after the experiment start time)'};
            app.text6.Text = 'Zeitgeber On:';
            app.txtLightHours = uieditfield(app.uipanel3, 'text');
            app.txtLightHours.ValueChangedFcn = createCallbackFcn(app, @txtLightHours_Callback, true);
            app.txtLightHours.Tooltip = 'Hours of Zeitgeber';
            app.txtLightHours.Value = '12';
            app.lblexpstart = uilabel(app.uipanel3);
            app.lblexpstart.Text = 'Exp. Start Time:';
            app.pbClear = uibutton(app.uipanel3, 'push');
            app.pbClear.ButtonPushedFcn = createCallbackFcn(app, @pbClear_Callback, true);
            app.pbClear.Tooltip = 'Clear selected channel list';
            app.pbClear.Text = 'x';
            app.txtRunName = uilabel(app.uipanel3);
            app.txtRunName.Text = '';
            app.text3 = uilabel(app.uipanel3);
            app.text3.Text = 'Run Name:';
            app.txtPlotTitle = uieditfield(app.fig, 'text');
            app.txtFolderOut = uieditfield(app.fig, 'text');
            app.txtFolderOut.Enable = 'off';
            app.text11 = uilabel(app.fig);
            app.text11.Text = 'Output Folder:';
            app.pbFolderOut = uibutton(app.fig, 'push');
            app.pbFolderOut.ButtonPushedFcn = createCallbackFcn(app, @pbFolderOut_Callback, true);
            app.pbFolderOut.Text = '...';
            app.text14 = uilabel(app.fig);
            app.text14.Text = 'Output File Name:';
            app.pbActiveNormalized = uibutton(app.fig, 'push');
            app.pbActiveNormalized.ButtonPushedFcn = createCallbackFcn(app, @pbActiveNormalized_Callback, true);
            app.pbActiveNormalized.Text = 'Normalized Activity Analysis';
            app.uibuttongroup11 = uibuttongroup(app.fig);
            app.uibuttongroup11.SelectionChangedFcn = createCallbackFcn(app, @uibuttongroup11SelectionChanged, true);
            app.uibuttongroup11.Title = 'Y-Axis Range';
            app.text52 = uilabel(app.uibuttongroup11);
            app.text52.Tooltip = 'Days to include in analysis. If specifying multiple days, separate with commas.';
            app.text52.Text = 'to';
            app.btnYaxisAuto = uiradiobutton(app.uibuttongroup11);
            app.btnYaxisAuto.Text = 'Autoscale Y Axis';
            app.btnYaxisAuto.Value = true;
            app.btnYaxisManual = uiradiobutton(app.uibuttongroup11);
            app.btnYaxisManual.Text = 'Specify Y Axis Range:';
            app.txtYaxisMin = uieditfield(app.uibuttongroup11, 'text');
            app.txtYaxisMin.Enable = 'off';
            app.txtYaxisMin.Tooltip = 'Min';
            app.txtYaxisMin.Value = '0';
            app.txtYaxisMax = uieditfield(app.uibuttongroup11, 'text');
            app.txtYaxisMax.Enable = 'off';
            app.txtYaxisMax.Tooltip = 'Min';
            app.txtYaxisMax.Value = '6';
            % Show the figure after all components are created
            app.fig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PHASE_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.fig)

            % Execute the startup function
            runStartupFcn(app, @PHASE_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.fig)
        end
    end
end